classdef Data_Explorer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataExplorerUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        VariableSelectionPanel        matlab.ui.container.Panel
        XDropDownLabel                matlab.ui.control.Label
        XDropDown                     matlab.ui.control.DropDown
        YDropDownLabel                matlab.ui.control.Label
        YDropDown                     matlab.ui.control.DropDown
        ZDropDownLabel                matlab.ui.control.Label
        ZDropDown                     matlab.ui.control.DropDown
        FlattenNonscalarY             matlab.ui.control.CheckBox
        FlattenNonscalarZ             matlab.ui.control.CheckBox
        FlattenNonscalarX             matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        ShapeErrorLabel               matlab.ui.control.Label
        DOptionsPanel                 matlab.ui.container.Panel
        DataMarkersCheckBox           matlab.ui.control.CheckBox
        UniformColorCheckBox          matlab.ui.control.CheckBox
        EditField                     matlab.ui.control.EditField
        ButtonGroup                   matlab.ui.container.ButtonGroup
        StdDevButton                  matlab.ui.control.RadioButton
        StdErrButton                  matlab.ui.control.RadioButton
        ErrorBarsCheckBox             matlab.ui.control.CheckBox
        LegendCheckBox                matlab.ui.control.CheckBox
        CurveFittingPanel             matlab.ui.container.Panel
        LaunchCurveFittingToolButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataNames
        DataValues
        DataSources
    end
    methods (Access = private)
        
        function  plotData(app)
            
            if isnumeric(app.XDropDown.Value)
                x_indices=app.XDropDown.Value;
            else
                x_indices = regexp(strtrim(app.XDropDown.Value),',[ \t\f]*','split');
                [~,x_indices,~]=intersect(app.DataNames,x_indices);
            end
            
            if isnumeric(app.YDropDown.Value)
                y_indices=app.YDropDown.Value;
            else
                y_indices = regexp(strtrim(app.YDropDown.Value),',[ \t\f]*','split');
                [~,y_indices,~]=intersect(app.DataNames,y_indices);
            end
            
            if isnumeric(app.ZDropDown.Value)
                z_indices=app.ZDropDown.Value;
            else
                z_indices = regexp(strtrim(app.ZDropDown.Value),',[ \t\f]*','split');
                [~,z_indices,~]=intersect(app.DataNames,z_indices);
            end
            
            x_name=app.DataNames(x_indices);
            y_name=app.DataNames(y_indices);
            z_name=app.DataNames(z_indices);
            
            %augment indices if needed for 2D plot
            if length(x_indices)==1 && length(y_indices)>1 && isempty(z_indices)
                x_indices=repmat(x_indices,size(y_indices));
            end
            
            if length(y_indices)==1 && length(x_indices)>1 && isempty(z_indices)
                y_indices=repmat(y_indices,size(x_indices));
            end
            
            x_data=app.DataValues(x_indices);
            y_data=app.DataValues(y_indices);
            z_data=app.DataValues(z_indices);
            
            cla(app.UIAxes);
            app.ShapeErrorLabel.Visible=false;
            if isempty(z_data)
                if isempty(x_data)
                    %                     ydata=ydata{1};
                    
                    
                    all_nonscalars=all(cellfun(@(y) all(cellfun(@(x) ~isscalar(x),y)),y_data));
                    
                    if ~all_nonscalars % categorical plotting
                        x_data=1:size(y_data,2);
                        bar_mean = cellfun(@(y) mean([y{:}]),y_data);
                        
                        bar(app.UIAxes,x_data,bar_mean);
                        if app.ErrorBarsCheckBox.Value
                            err_type=app.ButtonGroup.SelectedObject.Text;
                            if strcmpi(err_type,'std. dev.')
                                bar_err = cellfun(@(y) std([y{:}]),y_data);     
                            elseif strcmpi(err_type,'std. err.')
                                bar_err = cellfun(@(y) std([y{:}])/sqrt(numel([y{:}])),y_data);
                            else
                                bar_err = NaN(size(y_data));
                            end
                            hold(app.UIAxes,'on')
                            herr=errorbar(app.UIAxes,x_data,bar_mean,bar_err);
                            herr.Color=[0,0,0];
                            herr.LineStyle='none';
                            hold(app.UIAxes,'off')
                        end
                        
                        app.UIAxes.XTickLabel=y_name;
                        app.UIAxes.YLabel.String='Value(s)';
                        app.UIAxes.XLabel.String='';
                    else
                        
                        hold(app.UIAxes,'on')
                        cellfun(@(y) cellfun(@(yy) plot(app.UIAxes,yy),y),y_data)
                        hold(app.UIAxes,'off')
                        app.Ticks('auto','auto');
                        app.UIAxes.YLabel.String=strjoin(y_name,', ');
                        app.UIAxes.XLabel.String='';
                    end
                else
                    compatible_sizes=false(size(x_data,2),size(x_data{1},2));
                    for i=1:length(x_data)
                        try
                            compatible_sizes(i,:)=cellfun(@(x,y) ~isempty(x) && ~isempty(y) && (all(size(x)==size(y)) || all(circshift(size(x),1)==size(y))),x_data{i},y_data{i});
                        catch
                        end
                    end
                    
                    if ~any(compatible_sizes(:))
                        app.ShapeErrorLabel.Visible=true;
                    else
                        is_plottable=any(compatible_sizes,2);
                        
                        x_data=x_data(is_plottable);
                        if numel(y_name) == size(compatible_sizes,1)
                            y_name=y_name(is_plottable);
                        end
                        
                        y_data=y_data(is_plottable);
                        if numel(x_name) == size(compatible_sizes,1)
                            x_name=x_name(is_plottable);
                        end
                        
                        compatible_sizes = compatible_sizes(is_plottable,:);
                        all_nonscalars=all(cellfun(@(y) all(cellfun(@(x) ~isscalar(x),y)),y_data));
                        hplot=[];
                        hold(app.UIAxes,'on')
                        if all_nonscalars
                            
                            
                            for i=1:length(x_data)
                                tmp=compatible_sizes(i,:);
                                hplot(end+1:end+nnz(tmp))=cellfun(@(x,y) plot(app.UIAxes,x,y),x_data{i}(tmp),y_data{i}(tmp));
                            end

                            if app.LegendCheckBox.Value
                                labels=repmat(app.DataSources,size(x_data));
                                legend(app.UIAxes,hplot,labels{:});
                            end
                            
                        else
                            
                            for i=1:length(x_data)
                                tmp=compatible_sizes(i,:);
                                hplot(end+1)=plot(app.UIAxes,[x_data{i}{tmp}],[y_data{i}{tmp}]);
                            end

                            if app.LegendCheckBox.Value
                                legend(app.UIAxes,hplot,y_name{:});
                            end
                            
                        end
                        hold(app.UIAxes,'off')
                        app.Ticks('auto','auto')
                        app.UIAxes.YLabel.String=strjoin(y_name,', ');
                        app.UIAxes.XLabel.String=strjoin(x_name,', ');
                    end
                    
                end
            end
        end
        function Ticks(app,xt,yt)
            app.XTicks(xt);
            app.YTicks(yt);
        end
        function XTicks(app,xt)
            
            xlim(app.UIAxes,xt);
            xticks(app.UIAxes,xt);
            xt=xticks(app.UIAxes);
            app.UIAxes.XTickLabel=num2str(xt');
        end
        function YTicks(app,yt)
            ylim(app.UIAxes,yt);
            yticks(app.UIAxes,yt);
            yt=yticks(app.UIAxes);
            app.UIAxes.YTickLabel=num2str(yt');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %varargin{i} = {"VarName_i",{Data_i}} for i in 1:end-1
            % Data_i = cell(1,N) where there are N sets of "data" (scalar or vector values)
            % varargin{end} = {'}
            if isempty(varargin) %default values for testing purposes
                varargin={{'Var1',{3,4,5}}, {'Var2',{6,7,8}}, {'Var3',{[3 4 5],[],[6 7 8]}}, {'Var4',{[2 4 6],[1 3 5],[-1 2 -1]}},{'a','b','c'}};
            end
            
            app.DataNames = cellfun(@(x) x{1}, varargin(1:end-1), 'UniformOutput',0);
            app.DataValues = cellfun(@(x) x{2}, varargin(1:end-1), 'UniformOutput',0);
            app.DataSources = varargin{end};
            
            
            for DropDown=[app.XDropDown, app.YDropDown, app.ZDropDown]
                if ~isempty(DropDown.Items)
                    DropDown.ItemsData = [{[]} num2cell(1:length(app.DataNames))];
                else
                    DropDown.ItemsData = [num2cell(1:length(app.DataNames))];
                end
                DropDown.Items = [DropDown.Items app.DataNames];
            end
            
            
            app.plotData()
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            app.plotData()
        end

        % Value changed function: ZDropDown
        function ZDropDownValueChanged(app, event)
            app.plotData()
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            app.plotData()
        end

        % Callback function
        function StdDevCheckBoxValueChanged(app, event)
            value = app.StdDevCheckBox.Value;
            app.StdErrCheckBox.Value=~value;
        end

        % Callback function
        function StdErrCheckBoxValueChanged(app, event)
            value = app.StdErrCheckBox.Value;
            app.StdDevCheckBox.Value=~value;
        end

        % Value changed function: ErrorBarsCheckBox
        function ErrorBarsCheckBoxValueChanged(app, event)
            value = app.ErrorBarsCheckBox.Value;
            app.StdDevButton.Enable=value;
            app.StdErrButton.Enable=value;
            app.plotData()
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.plotData()
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            app.plotData()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataExplorerUIFigure = uifigure('Visible', 'off');
            app.DataExplorerUIFigure.Name = 'Data Explorer';
            app.GridLayout = uigridlayout(app.DataExplorerUIFigure);
            app.VariableSelectionPanel = uipanel(app.GridLayout);
            app.VariableSelectionPanel.Title = 'Variable Selection';
            app.XDropDownLabel = uilabel(app.VariableSelectionPanel);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.VariableSelectionPanel);
            app.XDropDown.Items = {'None (Non-Scalar/Categorical)'};
            app.XDropDown.Editable = 'on';
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Tooltip = {'Data to be plotted on the X-axis'};
            app.XDropDown.Value = 'None (Non-Scalar/Categorical)';
            app.YDropDownLabel = uilabel(app.VariableSelectionPanel);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.VariableSelectionPanel);
            app.YDropDown.Items = {};
            app.YDropDown.Editable = 'on';
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.YDropDown.Tooltip = {'Data to be plotted on the Y-axis'};
            app.YDropDown.Value = {};
            app.ZDropDownLabel = uilabel(app.VariableSelectionPanel);
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.VariableSelectionPanel);
            app.ZDropDown.Items = {'None (2D Plot)'};
            app.ZDropDown.ValueChangedFcn = createCallbackFcn(app, @ZDropDownValueChanged, true);
            app.ZDropDown.Tooltip = {'Optional: Data to be plotted on the Z-axis'};
            app.ZDropDown.Value = 'None (2D Plot)';
            app.FlattenNonscalarY = uicheckbox(app.VariableSelectionPanel);
            app.FlattenNonscalarY.Text = 'Flatten Non-scalars';
            app.FlattenNonscalarZ = uicheckbox(app.VariableSelectionPanel);
            app.FlattenNonscalarZ.Text = 'Flatten Non-scalars';
            app.FlattenNonscalarX = uicheckbox(app.VariableSelectionPanel);
            app.FlattenNonscalarX.Text = 'Flatten Non-scalars';
            app.Panel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ShapeErrorLabel = uilabel(app.Panel);
            app.ShapeErrorLabel.Visible = 'off';
            app.ShapeErrorLabel.Text = 'Selected data has incompatible shapes.';
            app.DOptionsPanel = uipanel(app.GridLayout);
            app.DOptionsPanel.Title = '2D Options';
            app.DataMarkersCheckBox = uicheckbox(app.DOptionsPanel);
            app.DataMarkersCheckBox.Text = 'Data Markers';
            app.UniformColorCheckBox = uicheckbox(app.DOptionsPanel);
            app.UniformColorCheckBox.Text = 'Uniform Color';
            app.EditField = uieditfield(app.DOptionsPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.ButtonGroup = uibuttongroup(app.DOptionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.StdDevButton = uiradiobutton(app.ButtonGroup);
            app.StdDevButton.Text = 'Std. Dev.';
            app.StdDevButton.Value = true;
            app.StdErrButton = uiradiobutton(app.ButtonGroup);
            app.StdErrButton.Text = 'Std. Err.';
            app.ErrorBarsCheckBox = uicheckbox(app.ButtonGroup);
            app.ErrorBarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErrorBarsCheckBoxValueChanged, true);
            app.ErrorBarsCheckBox.Text = 'Error Bars';
            app.ErrorBarsCheckBox.Value = true;
            app.LegendCheckBox = uicheckbox(app.DOptionsPanel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.CurveFittingPanel = uipanel(app.GridLayout);
            app.CurveFittingPanel.Title = 'Curve Fitting';
            app.LaunchCurveFittingToolButton = uibutton(app.CurveFittingPanel, 'push');
            app.LaunchCurveFittingToolButton.Text = 'Launch Curve Fitting Tool';
            % Show the figure after all components are created
            app.DataExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Explorer_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataExplorerUIFigure)
        end
    end
end